classdef ParachuteDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        DescentrateLabel                matlab.ui.control.Label
        ExportGoreDXFButton             matlab.ui.control.Button
        SelectParachuteShapeButtonGroup  matlab.ui.container.ButtonGroup
        FlatCircularButton              matlab.ui.control.RadioButton
        HemisphericalButton             matlab.ui.control.RadioButton
        ElipticalButton                 matlab.ui.control.RadioButton
        ToroidialButton                 matlab.ui.control.RadioButton
        DesignPanel                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        PercentHemispherSlider          matlab.ui.control.Slider
        DiameterSlider                  matlab.ui.control.Slider
        SpillHoleDiameterSlider         matlab.ui.control.Slider
        DonutDiameter                   matlab.ui.control.Slider
        PhiMin                          matlab.ui.control.Slider
        PhiMax                          matlab.ui.control.Slider
        DiameterEditFieldLabel          matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.NumericEditField
        HemisphericalEditFieldLabel     matlab.ui.control.Label
        HemisphericalEditField          matlab.ui.control.NumericEditField
        SpillHoleDiamterEditFieldLabel  matlab.ui.control.Label
        SpillHoleDiamterEditField       matlab.ui.control.NumericEditField
        DonutDiameterEditFieldLabel     matlab.ui.control.Label
        DonutDiameterEditField          matlab.ui.control.NumericEditField
        PhiMindegEditFieldLabel         matlab.ui.control.Label
        PhiMindegEditField              matlab.ui.control.NumericEditField
        PhiMaxdegEditFieldLabel         matlab.ui.control.Label
        PhiMaxdegEditField              matlab.ui.control.NumericEditField
        ResultsPanel                    matlab.ui.container.Panel
        SurfaceareaLabel                matlab.ui.control.Label
        ProjectedareaLabel              matlab.ui.control.Label
        EstimatedCdLabel                matlab.ui.control.Label
        SurfAreaDisp                    matlab.ui.control.Label
        ProjAreaDisp                    matlab.ui.control.Label
        EstCdDisp                       matlab.ui.control.Label
        UnitsButtonGroup                matlab.ui.container.ButtonGroup
        meterRadio                      matlab.ui.control.RadioButton
        feetRadio                       matlab.ui.control.RadioButton
        NumberofGoresEditFieldLabel     matlab.ui.control.Label
        NumberofGoresEditField          matlab.ui.control.NumericEditField
        RocketWeightkgEditFieldLabel    matlab.ui.control.Label
        RocketWeightkgEditField         matlab.ui.control.NumericEditField
        SeamWidthEditFieldLabel         matlab.ui.control.Label
        SeamWidthEditField              matlab.ui.control.NumericEditField
        DXFfileNameEditFieldLabel       matlab.ui.control.Label
        DXFfileNameEditField            matlab.ui.control.EditField
        OverideCdEditFieldLabel         matlab.ui.control.Label
        OverideCdEditField              matlab.ui.control.NumericEditField
        OverideCdCheckBox               matlab.ui.control.CheckBox
        Tab2                            matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Property % Description
    end


    methods (Access = private)
        
        function results = PlotParachute3D(app)
            if app.FlatCircularButton.Value == true
             
            elseif app.HemisphericalButton.Value == true
             R = app.DiameterSlider.Value/2;
             r = app.SpillHoleDiamterEditField.Value/2;
             pHem = app.PercentHemispherSlider.Value;
             ThetaB = pi/2 - pi*pHem/100;
             ThetaF = acos(r/R);
            Phi=linspace(ThetaB,ThetaF,20);
            Theta=linspace(0,2*pi,app.NumberofGoresEditField.Value+1);
            [Phi,Theta]=meshgrid(Phi,Theta);
            [X,Y,Z]=sph2cart(Theta,Phi,R);


            surf(app.UIAxes, X,Z,Y)
            app.UIAxes.PlotBoxAspectRatio = [1 1 1];
            app.UIAxes.XLim = [-100 100];
            app.UIAxes.YLim = [-100 100];
            app.UIAxes.ZLim = [-100 100];
            elseif app.ElipticalButton.Value == true
                
            else 
            R=app.DiameterSlider.Value/2; % outer radius of torus
            r=app.DonutDiameter.Value/2; % inner tube radius
            phiMin = app.PhiMin.Value*pi/180;
            phiMax = app.PhiMax.Value*pi/180;
            th=linspace(phiMin,phiMax, 18); % e.g. 36 partitions along perimeter of the tube 
            phi=linspace(0,2*pi,app.NumberofGoresEditField.Value + 1); % e.g. 18 partitions along azimuth of torus
            % we convert our vectors phi and th to [n x n] matrices with meshgrid command:
            [Phi,Th]=meshgrid(phi,th); 
            % now we generate n x n matrices for x,y,z according to eqn of torus
            x=(R+r.*cos(Th)).*cos(Phi);
            y=(R+r.*cos(Th)).*sin(Phi);
            z=r.*sin(Th);
            surf(app.UIAxes, x,y,z)
            app.UIAxes.PlotBoxAspectRatio = [1 1 1];
            app.UIAxes.XLim = [-100 100];
            app.UIAxes.YLim = [-100 100];
            app.UIAxes.ZLim = [-100 100];
            end
        end
        
        function results = PlotGore(app)
            
            if app.FlatCircularButton.Value == true

            elseif app.HemisphericalButton.Value == true
                D = app.DiameterSlider.Value;
                R = D/2;
                N = app.NumberofGoresEditField.Value; %Number of Gores
                d = app.SpillHoleDiameterSlider.Value;
                r = d/2;
                s = R*asin(r/R);
                Seam = app.SeamWidthEditField.Value;
                pHem = app.PercentHemispherSlider.Value;
                ThetaB = pi/2 - pi*pHem/100;
                
                S = linspace(s,(1/2*pi-ThetaB)*R,100); %Arc length
                w = 1/N*2*pi*R*sin(S/R)/2;
                m = atan(-1/N*2*pi*cos(S/R)/2);
    
                SSeam = S + sin(m)*Seam;
                wSeam = w + cos(m)*Seam;
                
                v = flip(-w);
                Sv = flip(S);
                vSeam = flip(-wSeam);
                SvSeam = flip(SSeam);
                
                
                S = cat(2,S,Sv,S(1));
                W = cat(2,w,v,w(1));
                SS = cat(2,SSeam,SvSeam,SSeam(1));
                WS = cat(2,wSeam,vSeam,wSeam(1));
                
                plot(app.UIAxes2, S,W,SS,WS)
                app.UIAxes2.PlotBoxAspectRatio = [1 1 1];
                app.UIAxes2.XLim = [0 100];
                app.UIAxes2.YLim = [-50 50];
                results = [S;W;SS;WS];
            elseif app.ElipticalButton.Value == true
            else
                D = app.DiameterEditField.Value;
                R = D/2;
                d = app.DonutDiameter.Value;
                r = d/2;
                ThetaMax = pi - app.PhiMindegEditField.Value * pi/180 ;
                ThetaMin = pi - app.PhiMaxdegEditField.Value * pi/180;
                s = linspace(ThetaMin*r,ThetaMax*r,100);
                N= app.NumberofGoresEditField.Value;
                w = (R-r.*(cos(s/r)))*2*pi./(2*N);
                
                v = flip(-w);
                Sv = flip(s);
                S = cat(2,s,Sv,s(1));
                W = cat(2,w,v,w(1));
                plot(app.UIAxes2, S,W)
                app.UIAxes2.PlotBoxAspectRatio = [1 1 1];
                app.UIAxes2.XLim = [0 100];
                app.UIAxes2.YLim = [-50 50];
                results = [S;W];
            end
        end
        
        function results = CalcArea(app)
            D = app.DiameterEditField.Value;
            d = app.SpillHoleDiamterEditField.Value;
            pH = app.PercentHemispherSlider.Value;
            dd = app.DonutDiameter.Value;
            phiMin = app.PhiMin.Value;
            phiMax = app.PhiMax.Value;
            
            if app.meterRadio.Value == true
                D = D*2.54/100;
                d = d*2.54/100;
                dd = dd*2.54/100;
            else
                D = D/12;
                d = d/12;
                dd = dd/12;
            end
            
            if app.FlatCircularButton.Value == true
                PArea = pi*((D/2)^2 - (d/2)^2);
                SArea = PArea;
            elseif app.HemisphericalButton.Value == true
                
                if pH*pi/100 < pi/2
                    PArea = ((sin(pH*pi/100)*D/2)^2 - (d/2)^2)*pi;
                else 
                    PArea = pi*((D/2)^2 - (d/2)^2);
                end
                SArea = 2*pi*((1-cos(pH*pi/100))*(D/2)^2 - d/2*(D/2 - ((D/2)^2 -(d/2)^2 )^(0.5)));
            elseif app.ElipticalButton.Value == true
                
            else
                PArea = pi*((D/2)^2 - (D/2 - dd)^2);
                SArea = PArea;
            end
            results = [PArea, SArea];
        end
        
        function Cd = EstimateCd(app)
            
            
            if app.FlatCircularButton.Value == true
                Cd = 0.8;
            elseif app.HemisphericalButton.Value == true
                Cd = 1;
            elseif app.ElipticalButton.Value == true
                
            else
                Cd = 2.2;
            end
            
            app.EstCdDisp.Text = num2str(Cd);
        end
        
        function Des = CalcDes(app)
            rho = 1.23;
            g = 9.8;
            
            Results = CalcArea(app);
            SArea = Results(1,2);
            PArea = Results(1,1);
            
            if app.feetRadio.Value == true
                PArea = PArea*0.092903;
                SArea = SArea*0.092903;
            end
            
            if app.OverideCdCheckBox.Value == true
                Cd = app.OverideCdEditField.Value;
            else
                Cd = EstimateCd(app);
            end
            
            M = app.RocketWeightkgEditField.Value;
            Des = (2*M*g/(rho*SArea*Cd))^(1/2);
            
            if app.feetRadio.Value == true
              Des = Des*32/9.8;
              Disp =  cat(2,'Descent Rate ',num2str(Des),' ft/s');
            else
              Disp =  cat(2,'Descent Rate ',num2str(Des),' m/s');
            end
           app.DescentrateLabel.Text = Disp;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: DiameterSlider, DiameterSlider
        function ASliderValueChanged(app, event)
            PlotParachute3D(app);
            PlotGore(app);
            value = app.DiameterSlider.Value;
            app.DiameterEditField.Value = value; 
            
            Results = CalcArea(app);
            app.SurfAreaDisp.Text = num2str(Results(1,2));
            app.ProjAreaDisp.Text = num2str(Results(1,1));
            
            EstimateCd(app);
            CalcDes(app);
        end

        % Value changed function: PercentHemispherSlider
        function PercentHemispherSliderValueChanged(app, event)
            value = app.PercentHemispherSlider.Value;
            app.HemisphericalEditField.Value = value;
            PlotParachute3D(app);
            PlotGore(app);
            
            Results = CalcArea(app);
            app.SurfAreaDisp.Text = num2str(Results(1,2));
            app.ProjAreaDisp.Text = num2str(Results(1,1));
            
            EstimateCd(app);
            CalcDes(app);
        end

        % Callback function
        function SelectParachuteShapeButtonGroupSizeChanged(app, event)
            position = app.SelectParachuteShapeButtonGroup.Position;
            app.DiameterSlider.Visible = 'false';
        end

        % Selection changed function: 
        % SelectParachuteShapeButtonGroup
        function SelectParachuteShapeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectParachuteShapeButtonGroup.SelectedObject;
            PlotParachute3D(app);
            if app.FlatCircularButton.Value == true
                app.DiameterSlider.Visible = 'on';
                app.DiameterEditField.Visible = 'on';
                app.DiameterEditFieldLabel.Visible = 'on';
                
                app.PercentHemispherSlider.Visible = 'off';
                app.HemisphericalEditField.Visible = 'off';
                app.HemisphericalEditFieldLabel.Visible = 'off';
                
                app.SpillHoleDiameterSlider.Visible = 'on';
                app.SpillHoleDiamterEditField.Visible = 'on';
                app.SpillHoleDiamterEditFieldLabel.Visible = 'on';
                
                app.DonutDiameter.Visible = 'off';
                app.DonutDiameterEditField.Visible = 'off';
                app.DonutDiameterEditFieldLabel.Visible = 'off';
                
                app.PhiMin.Visible = 'off';
                app.PhiMindegEditField.Visible = 'off';
                app.PhiMindegEditFieldLabel.Visible = 'off';
                
                app.PhiMax.Visible = 'off';
                app.PhiMaxdegEditField.Visible = 'off';
                app.PhiMaxdegEditFieldLabel.Visible = 'off';
                
            elseif app.HemisphericalButton.Value == true
                app.DiameterSlider.Visible = 'on';
                app.DiameterEditField.Visible = 'on';
                app.DiameterEditFieldLabel.Visible = 'on';
                
                app.PercentHemispherSlider.Visible = 'on';
                app.HemisphericalEditField.Visible = 'on';
                app.HemisphericalEditFieldLabel.Visible = 'on';
                
                app.SpillHoleDiameterSlider.Visible = 'on';
                app.SpillHoleDiamterEditField.Visible = 'on';
                app.SpillHoleDiamterEditFieldLabel.Visible = 'on';
                
                app.DonutDiameter.Visible = 'off';
                app.DonutDiameterEditField.Visible = 'off';
                app.DonutDiameterEditFieldLabel.Visible = 'off';
                
                app.PhiMin.Visible = 'off';
                app.PhiMindegEditField.Visible = 'off';
                app.PhiMindegEditFieldLabel.Visible = 'off';
                
                app.PhiMax.Visible = 'off';
                app.PhiMaxdegEditField.Visible = 'off';
                app.PhiMaxdegEditFieldLabel.Visible = 'off';
            elseif app.ElipticalButton.Value == true
                app.DiameterSlider.Visible = 'off';
                app.DiameterEditField.Visible = 'off';
                app.DiameterEditFieldLabel.Visible = 'off';
                
                app.PercentHemispherSlider.Visible = 'off';
                app.HemisphericalEditField.Visible = 'off';
                app.HemisphericalEditFieldLabel.Visible = 'off';
                
                app.SpillHoleDiameterSlider.Visible = 'off';
                app.SpillHoleDiamterEditField.Visible = 'off';
                app.SpillHoleDiamterEditFieldLabel.Visible = 'off';
                
                app.DonutDiameter.Visible = 'off';
                app.DonutDiameterEditField.Visible = 'off';
                app.DonutDiameterEditFieldLabel.Visible = 'off';
                
                app.PhiMin.Visible = 'off';
                app.PhiMindegEditField.Visible = 'off';
                app.PhiMindegEditFieldLabel.Visible = 'off';
                
                app.PhiMax.Visible = 'off';
                app.PhiMaxdegEditField.Visible = 'off';
                app.PhiMaxdegEditFieldLabel.Visible = 'off';
            else
                app.DiameterSlider.Visible = 'on';
                app.DiameterEditField.Visible = 'on';
                app.DiameterEditFieldLabel.Visible = 'on';
                
                app.PercentHemispherSlider.Visible = 'off';
                app.HemisphericalEditField.Visible = 'off';
                app.HemisphericalEditFieldLabel.Visible = 'off';
                
                app.SpillHoleDiameterSlider.Visible = 'off';
                app.SpillHoleDiamterEditField.Visible = 'off';
                app.SpillHoleDiamterEditFieldLabel.Visible = 'off';
                
                app.DonutDiameter.Visible = 'on';
                app.DonutDiameterEditField.Visible = 'on';
                app.DonutDiameterEditFieldLabel.Visible = 'on';
                
                app.PhiMin.Visible = 'on';
                app.PhiMindegEditField.Visible = 'on';
                app.PhiMindegEditFieldLabel.Visible = 'on';
                
                app.PhiMax.Visible = 'on';
                app.PhiMaxdegEditField.Visible = 'on';
                app.PhiMaxdegEditFieldLabel.Visible = 'on';
            end
            PlotParachute3D(app);
            PlotGore(app);
        end

        % Value changed function: DiameterEditField
        function DiameterEditFieldValueChanged(app, event)
            value = app.DiameterEditField.Value;
            app.DiameterSlider.Value = value;
            PlotParachute3D(app);
            PlotGore(app)
            
            Results = CalcArea(app);
            app.SurfAreaDisp.Text = num2str(Results(1,2));
            app.ProjAreaDisp.Text = num2str(Results(1,1));
            
            EstimateCd(app);
            CalcDes(app);
        end

        % Value changed function: SpillHoleDiamterEditField
        function SpillHoleDiamterEditFieldValueChanged(app, event)
            value = app.SpillHoleDiamterEditField.Value;
            app.SpillHoleDiameterSlider.Value = value;
            PlotParachute3D(app);
            
            Results = CalcArea(app);
            app.SurfAreaDisp.Text = num2str(Results(1,2));
            app.ProjAreaDisp.Text = num2str(Results(1,1));
            
            EstimateCd(app);
            CalcDes(app);
        end

        % Value changed function: SpillHoleDiameterSlider
        function SpillHoleDiameterSliderValueChanged(app, event)
            value = app.SpillHoleDiameterSlider.Value;
            app.SpillHoleDiamterEditField.Value = value;
            PlotParachute3D(app);
            PlotGore(app);
            
            Results = CalcArea(app);
            app.SurfAreaDisp.Text = num2str(Results(1,2));
            app.ProjAreaDisp.Text = num2str(Results(1,1));
            
            EstimateCd(app);
            CalcDes(app);
        end

        % Value changed function: NumberofGoresEditField
        function NumberofGoresEditFieldValueChanged(app, event)
             PlotGore(app);
             PlotParachute3D(app);
        end

        % Value changed function: HemisphericalEditField
        function HemisphericalEditFieldValueChanged(app, event)
            value = app.HemisphericalEditField.Value;
            app.PercentHemispherSlider.Value = value;
            PlotParachute3D(app);
            PlotGore(app);
            
            Results = CalcArea(app);
            app.SurfAreaDisp.Text = num2str(Results(1,2));
            app.ProjAreaDisp.Text = num2str(Results(1,1));
        end

        % Button pushed function: ExportGoreDXFButton
        function ExportGoreDXFButtonPushed(app, event)
            Results = PlotGore(app);
             A = Results(1,:);
             B = Results(2,:);
             %C = Results(3,:);
             %D = Results(4,:);
             
            Z = zeros(size(A))';
            A = A';
            B = B';
            selpath = uigetdir;
            fileName = app.DXFfileNameEditField.Value;
            fileName = strcat(selpath,'\',fileName,'.dxf');
            app.DescentrateLabel.Text = selpath;
            FID = dxf_open(fileName);
            dxf_polyline(FID,A,B,Z);
            dxf_close(FID);


        end

        % Value changed function: PhiMin
        function PhiMinValueChanged(app, event)
            value = app.PhiMin.Value;
            app.PhiMindegEditField.Value = value;
            
            PlotParachute3D(app);
            PlotGore(app);
            
            Results = CalcArea(app);
            app.SurfAreaDisp.Text = num2str(Results(1,2));
            app.ProjAreaDisp.Text = num2str(Results(1,1));
            
            EstimateCd(app);
            CalcDes(app);
        end

        % Value changed function: PhiMindegEditField
        function PhiMindegEditFieldValueChanged(app, event)
            value = app.PhiMindegEditField.Value;
            app.PhiMin.Value = value;
            
            PlotParachute3D(app);
            PlotGore(app);
            
            
            EstimateCd(app);
            CalcDes(app);
        end

        % Value changed function: PhiMax
        function PhiMaxValueChanged(app, event)
            value = app.PhiMax.Value;
            app.PhiMaxdegEditField.Value = value;
            
            PlotParachute3D(app);
            PlotGore(app);
            
            Results = CalcArea(app);
            app.SurfAreaDisp.Text = num2str(Results(1,2));
            app.ProjAreaDisp.Text = num2str(Results(1,1));
            
            EstimateCd(app);
            CalcDes(app);
        end

        % Value changed function: PhiMaxdegEditField
        function PhiMaxdegEditFieldValueChanged(app, event)
            value = app.PhiMaxdegEditField.Value;
            app.PhiMax.Value = value;
            
            PlotParachute3D(app);
            PlotGore(app);
            
            Results = CalcArea(app);
            app.SurfAreaDisp.Text = num2str(Results(1,2));
            app.ProjAreaDisp.Text = num2str(Results(1,1));
            
            EstimateCd(app);
            CalcDes(app);
        end

        % Value changed function: DonutDiameter
        function DonutDiameterValueChanged(app, event)
            value = app.DonutDiameter.Value;
            app.DonutDiameterEditField.Value = value;
            
            PlotParachute3D(app);
            PlotGore(app);
            
            Results = CalcArea(app);
            app.SurfAreaDisp.Text = num2str(Results(1,2));
            app.ProjAreaDisp.Text = num2str(Results(1,1));
            
            EstimateCd(app);
            CalcDes(app);
        end

        % Value changed function: DonutDiameterEditField
        function DonutDiameterEditFieldValueChanged(app, event)
            value = app.DonutDiameterEditField.Value;
            app.DonutDiameter.Value = value;
            
            PlotParachute3D(app);
            PlotGore(app);
            
            Results = CalcArea(app);
            app.SurfAreaDisp.Text = num2str(Results(1,2));
            app.ProjAreaDisp.Text = num2str(Results(1,1));
            
            EstimateCd(app);
            CalcDes(app);
        end

        % Selection changed function: UnitsButtonGroup
        function UnitsButtonGroupSelectionChanged(app, event)
            selectedButton = app.UnitsButtonGroup.SelectedObject;
            CalcArea(app);
            
            Results = CalcArea(app);
            app.SurfAreaDisp.Text = num2str(Results(1,2));
            app.ProjAreaDisp.Text = num2str(Results(1,1));
            
            EstimateCd(app);
            CalcDes(app);
        end

        % Value changed function: RocketWeightkgEditField
        function RocketWeightkgEditFieldValueChanged(app, event)
            value = app.RocketWeightkgEditField.Value;
            
            EstimateCd(app);
            CalcDes(app);
        end

        % Value changed function: OverideCdEditField
        function OverideCdEditFieldValueChanged(app, event)
            value = app.OverideCdEditField.Value;
            
            EstimateCd(app);
            CalcDes(app);
        end

        % Value changed function: SeamWidthEditField
        function SeamWidthEditFieldValueChanged(app, event)
            value = app.SeamWidthEditField.Value;
            PlotGore(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.XLim = [-10 90];
            app.UIAxes2.YLim = [-50 50];
            app.UIAxes2.XTick = [-10 0 10 20 30 40 50 60 70 80 90];
            app.UIAxes2.XTickLabel = {'-10'; '0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'};
            app.UIAxes2.YTick = [-50 -25 0 25 50];
            app.UIAxes2.YTickLabel = {'-50'; '-25'; '0'; '25'; '50'};
            app.DescentrateLabel = uilabel(app.Tab);
            app.DescentrateLabel.Text = 'Descent rate:';
            app.ExportGoreDXFButton = uibutton(app.Tab, 'push');
            app.ExportGoreDXFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGoreDXFButtonPushed, true);
            app.ExportGoreDXFButton.Text = 'Export Gore DXF';
            app.SelectParachuteShapeButtonGroup = uibuttongroup(app.Tab);
            app.SelectParachuteShapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectParachuteShapeButtonGroupSelectionChanged, true);
            app.SelectParachuteShapeButtonGroup.Title = 'Select Parachute Shape';
            app.FlatCircularButton = uiradiobutton(app.SelectParachuteShapeButtonGroup);
            app.FlatCircularButton.Text = 'Flat Circular';
            app.FlatCircularButton.Value = true;
            app.HemisphericalButton = uiradiobutton(app.SelectParachuteShapeButtonGroup);
            app.HemisphericalButton.Text = 'Hemispherical';
            app.ElipticalButton = uiradiobutton(app.SelectParachuteShapeButtonGroup);
            app.ElipticalButton.Text = 'Eliptical';
            app.ToroidialButton = uiradiobutton(app.SelectParachuteShapeButtonGroup);
            app.ToroidialButton.Text = 'Toroidial';
            app.DesignPanel = uipanel(app.Tab);
            app.DesignPanel.Title = 'Design';
            app.UIAxes = uiaxes(app.DesignPanel);
            title(app.UIAxes, 'Parachute Displayer')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PercentHemispherSlider = uislider(app.DesignPanel);
            app.PercentHemispherSlider.ValueChangedFcn = createCallbackFcn(app, @PercentHemispherSliderValueChanged, true);
            app.PercentHemispherSlider.Value = 50;
            app.DiameterSlider = uislider(app.DesignPanel);
            app.DiameterSlider.ValueChangedFcn = createCallbackFcn(app, @ASliderValueChanged, true);
            app.DiameterSlider.ValueChangingFcn = createCallbackFcn(app, @ASliderValueChanged, true);
            app.SpillHoleDiameterSlider = uislider(app.DesignPanel);
            app.SpillHoleDiameterSlider.ValueChangedFcn = createCallbackFcn(app, @SpillHoleDiameterSliderValueChanged, true);
            app.DonutDiameter = uislider(app.DesignPanel);
            app.DonutDiameter.ValueChangedFcn = createCallbackFcn(app, @DonutDiameterValueChanged, true);
            app.PhiMin = uislider(app.DesignPanel);
            app.PhiMin.Limits = [0 360];
            app.PhiMin.ValueChangedFcn = createCallbackFcn(app, @PhiMinValueChanged, true);
            app.PhiMax = uislider(app.DesignPanel);
            app.PhiMax.Limits = [0 360];
            app.PhiMax.ValueChangedFcn = createCallbackFcn(app, @PhiMaxValueChanged, true);
            app.PhiMax.Value = 90;
            app.DiameterEditFieldLabel = uilabel(app.DesignPanel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.DesignPanel, 'numeric');
            app.DiameterEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterEditFieldValueChanged, true);
            app.HemisphericalEditFieldLabel = uilabel(app.DesignPanel);
            app.HemisphericalEditFieldLabel.Text = '% Hemispherical';
            app.HemisphericalEditField = uieditfield(app.DesignPanel, 'numeric');
            app.HemisphericalEditField.ValueChangedFcn = createCallbackFcn(app, @HemisphericalEditFieldValueChanged, true);
            app.HemisphericalEditField.Value = 50;
            app.SpillHoleDiamterEditFieldLabel = uilabel(app.DesignPanel);
            app.SpillHoleDiamterEditFieldLabel.Text = 'Spill Hole Diamter';
            app.SpillHoleDiamterEditField = uieditfield(app.DesignPanel, 'numeric');
            app.SpillHoleDiamterEditField.ValueChangedFcn = createCallbackFcn(app, @SpillHoleDiamterEditFieldValueChanged, true);
            app.DonutDiameterEditFieldLabel = uilabel(app.DesignPanel);
            app.DonutDiameterEditFieldLabel.Text = 'Donut Diameter';
            app.DonutDiameterEditField = uieditfield(app.DesignPanel, 'numeric');
            app.DonutDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @DonutDiameterEditFieldValueChanged, true);
            app.PhiMindegEditFieldLabel = uilabel(app.DesignPanel);
            app.PhiMindegEditFieldLabel.Text = 'Phi  Min (deg)';
            app.PhiMindegEditField = uieditfield(app.DesignPanel, 'numeric');
            app.PhiMindegEditField.ValueChangedFcn = createCallbackFcn(app, @PhiMindegEditFieldValueChanged, true);
            app.PhiMaxdegEditFieldLabel = uilabel(app.DesignPanel);
            app.PhiMaxdegEditFieldLabel.Text = 'Phi Max (deg)';
            app.PhiMaxdegEditField = uieditfield(app.DesignPanel, 'numeric');
            app.PhiMaxdegEditField.ValueChangedFcn = createCallbackFcn(app, @PhiMaxdegEditFieldValueChanged, true);
            app.PhiMaxdegEditField.Value = 90;
            app.ResultsPanel = uipanel(app.Tab);
            app.ResultsPanel.Title = 'Results';
            app.SurfaceareaLabel = uilabel(app.ResultsPanel);
            app.SurfaceareaLabel.Text = 'Surface area:';
            app.ProjectedareaLabel = uilabel(app.ResultsPanel);
            app.ProjectedareaLabel.Text = 'Projected area:';
            app.EstimatedCdLabel = uilabel(app.ResultsPanel);
            app.EstimatedCdLabel.Text = 'Estimated Cd:';
            app.SurfAreaDisp = uilabel(app.ResultsPanel);
            app.SurfAreaDisp.Text = {''; ''};
            app.ProjAreaDisp = uilabel(app.ResultsPanel);
            app.ProjAreaDisp.Text = '';
            app.EstCdDisp = uilabel(app.ResultsPanel);
            app.EstCdDisp.Text = '';
            app.UnitsButtonGroup = uibuttongroup(app.Tab);
            app.UnitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UnitsButtonGroupSelectionChanged, true);
            app.UnitsButtonGroup.Title = 'Units';
            app.meterRadio = uiradiobutton(app.UnitsButtonGroup);
            app.meterRadio.Text = 'm^2';
            app.meterRadio.Value = true;
            app.feetRadio = uiradiobutton(app.UnitsButtonGroup);
            app.feetRadio.Text = 'ft^2';
            app.NumberofGoresEditFieldLabel = uilabel(app.Tab);
            app.NumberofGoresEditFieldLabel.Text = 'Number of Gores';
            app.NumberofGoresEditField = uieditfield(app.Tab, 'numeric');
            app.NumberofGoresEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofGoresEditFieldValueChanged, true);
            app.NumberofGoresEditField.Value = 6;
            app.RocketWeightkgEditFieldLabel = uilabel(app.Tab);
            app.RocketWeightkgEditFieldLabel.Text = 'Rocket Weight (kg)';
            app.RocketWeightkgEditField = uieditfield(app.Tab, 'numeric');
            app.RocketWeightkgEditField.ValueChangedFcn = createCallbackFcn(app, @RocketWeightkgEditFieldValueChanged, true);
            app.SeamWidthEditFieldLabel = uilabel(app.Tab);
            app.SeamWidthEditFieldLabel.Text = 'Seam Width';
            app.SeamWidthEditField = uieditfield(app.Tab, 'numeric');
            app.SeamWidthEditField.ValueChangedFcn = createCallbackFcn(app, @SeamWidthEditFieldValueChanged, true);
            app.DXFfileNameEditFieldLabel = uilabel(app.Tab);
            app.DXFfileNameEditFieldLabel.Text = 'DXF file Name';
            app.DXFfileNameEditField = uieditfield(app.Tab, 'text');
            app.DXFfileNameEditField.Value = 'DEFAULT_NAME';
            app.OverideCdEditFieldLabel = uilabel(app.Tab);
            app.OverideCdEditFieldLabel.Text = 'Overide Cd';
            app.OverideCdEditField = uieditfield(app.Tab, 'numeric');
            app.OverideCdEditField.ValueChangedFcn = createCallbackFcn(app, @OverideCdEditFieldValueChanged, true);
            app.OverideCdCheckBox = uicheckbox(app.Tab);
            app.OverideCdCheckBox.Text = 'Overide Cd';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParachuteDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end